classdef NJApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        extra_label            matlab.ui.control.Label
        VechnyButton           matlab.ui.control.Button
        categories_list_box    matlab.ui.control.ListBox
        KategorieListBoxLabel  matlab.ui.control.Label
        score_label            matlab.ui.control.Label
        ScoreLabel             matlab.ui.control.Label
        ControlPanel           matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        Button3                matlab.ui.control.Button
        STARTButton            matlab.ui.control.Button
        LOADEXCELButton        matlab.ui.control.Button
        word_label             matlab.ui.control.Label
        pick_answer_box        matlab.ui.control.ListBox
        SlovoLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        words_table
        selected_category
        only_selected_category = 1

        current_table
        current_czech_word
        current_german_word

        score
        total_words
        word_is_picking
        currently_selected_word = []
        passed_the_level
        failed_round
    end

    properties (Constant, Access = private)
        selected_words = 5
    end
    
    
    methods (Access = private)
        
        function loadWords(app)
            file_name = dir('*.xlsx').name;
            app.words_table = readtable(file_name);
        end

        function printScore(app)
            app.score_label.Text = [num2str(app.score), ' / ', num2str(app.total_words)];
        end

        function addWordsToTheList(app, word_list)
            shuffled_word_list = word_list(randperm(length(word_list)));
            for i = 1:length(word_list)
                app.addWordToTheList(shuffled_word_list{i});
            end
        end
        function addWordToTheList(app, word)
            if isempty(app.pick_answer_box.Items)
                app.pick_answer_box.Items{1} = word;
            else
                app.pick_answer_box.Items{end+1} = word;
            end
        end
        function addCategoriesToTheList(app, categories)
            for i = 1:length(categories)
                app.addCategoryToTheList(categories{i})
            end
        end
        function addCategoryToTheList(app, category)
            if isempty(app.categories_list_box.Items)
                app.categories_list_box.Items{1} = category;
            else
                app.categories_list_box.Items{end+1} = category;
            end
        end

        function clearList(app)
            app.pick_answer_box.Items = {};
        end
        function clearCategories(app)
            app.categories_list_box.Items = {};
        end

        function fillCategories(app)
            app.clearCategories();
            app.addCategoriesToTheList(unique(app.words_table.('Kategorie')));
        end

        function category = getSelectedCategory(app)
            category = app.categories_list_box.Value;
        end

        function selectCategory(app, item_number)
            if item_number > numel(app.categories_list_box.Items)
                error('Hight item number than maximum')
            end
            app.categories_list_box.Value = app.categories_list_box.Items{item_number};
        end

        function startQuiz(app)
            app.extra_label.Text = '';
            %Category selections
            
            if ~isempty(app.getSelectedCategory)
                selected_rows = strcmp(app.words_table.('Kategorie'), app.getSelectedCategory);
            else
                selected_rows = 1:size(app.words_table,1);
            end
            
            app.current_table = app.words_table(selected_rows, :);
            app.total_words = size(app.current_table,1);

            index_array = randperm(app.total_words);
            app.word_is_picking = 1;
            for i = index_array
                app.current_czech_word = app.current_table.('Ceske_slovo'){i};
                app.current_german_word = app.current_table.('Nemecke_slovo'){i};
                
                if app.only_selected_category
                    random_words = app.current_table.('Nemecke_slovo')(randperm(size(app.current_table,1),app.selected_words));
                else
                    random_words = app.words_table.('Nemecke_slovo')(randperm(size(app.words_table,1),app.selected_words));
                end

                app.word_label.Text = app.current_czech_word;
                app.clearList()
                app.addWordsToTheList(unique([random_words; {app.current_german_word}]))
                app.failed_round = 0;
                app.passed_the_level = 0;
                while(~app.passed_the_level)
                    pause(0.01)
                end
                app.printScore();
                app.word_is_picking = 1;
            end
            if app.score == app.total_words
                app.extra_label.Text = 'Gratulace, plné scóre';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Init translator
            addpath("TranslatorFiles\")
            
            
            %Load words from txts
            app.loadWords();
            app.fillCategories();
            app.selectCategory(1);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.score = 0;
            app.total_words = 0;
            app.startQuiz()
        end

        % Value changed function: pick_answer_box
        function pick_answer_boxValueChanged(app, event)
            app.currently_selected_word = app.pick_answer_box.Value;
            if strcmp(app.currently_selected_word, app.current_german_word)
                if ~app.failed_round
                    app.score = app.score+1;
                end
                app.passed_the_level = 1;
            else
                app.failed_round = 1;
            end
        end

        % Callback function
        function VechnyCheckBoxValueChanged(app, event)

        end

        % Button pushed function: VechnyButton
        function VechnyButtonPushed(app, event)
            app.categories_list_box.Value = {};
        end

        % Button pushed function: LOADEXCELButton
        function LOADEXCELButtonPushed(app, event)
            app.loadWords()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SlovoLabel = uilabel(app.UIFigure);
            app.SlovoLabel.Text = 'Slovo:';
            app.pick_answer_box = uilistbox(app.UIFigure);
            app.pick_answer_box.ValueChangedFcn = createCallbackFcn(app, @pick_answer_boxValueChanged, true);
            app.word_label = uilabel(app.UIFigure);
            app.word_label.Text = 'Vybrané slovo';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlPanel);
            app.LOADEXCELButton = uibutton(app.GridLayout, 'push');
            app.LOADEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @LOADEXCELButtonPushed, true);
            app.LOADEXCELButton.Text = 'LOAD EXCEL';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Button3 = uibutton(app.GridLayout, 'push');
            app.Button3.Text = 'Button3';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score:';
            app.score_label = uilabel(app.UIFigure);
            app.score_label.Text = '0';
            app.KategorieListBoxLabel = uilabel(app.UIFigure);
            app.KategorieListBoxLabel.Text = 'Kategorie';
            app.categories_list_box = uilistbox(app.UIFigure);
            app.VechnyButton = uibutton(app.UIFigure, 'push');
            app.VechnyButton.ButtonPushedFcn = createCallbackFcn(app, @VechnyButtonPushed, true);
            app.VechnyButton.Text = 'Všechny';
            app.extra_label = uilabel(app.UIFigure);
            app.extra_label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NJApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end